classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadImagesPanel                 matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        LeftViewEditFieldLabel          matlab.ui.control.Label
        LeftViewEditField               matlab.ui.control.EditField
        LoadButton_2                    matlab.ui.control.Button
        RightViewEditFieldLabel         matlab.ui.control.Label
        RightViewEditField              matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        BrowseButton_2                  matlab.ui.control.Button
        PlotsPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        extractfigureButton             matlab.ui.control.Button
        extractfigureButton_2           matlab.ui.control.Button
        extractfigureButton_3           matlab.ui.control.Button
        VirtualViewOptionsPanel         matlab.ui.container.Panel
        perspectiveSliderLabel          matlab.ui.control.Label
        perspectiveSlider               matlab.ui.control.Slider
        ShowvirtualViewButton           matlab.ui.control.Button
        extractPlotsButton              matlab.ui.control.Button
        ParameterSettingsPanel          matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        harrisTab                       matlab.ui.container.Tab
        tile_sizeEditField_3            matlab.ui.control.NumericEditField
        segment_lenghtEditField_2Label  matlab.ui.control.Label
        segment_lenghtEditField_2       matlab.ui.control.NumericEditField
        kEditField_2Label               matlab.ui.control.Label
        kEditField_2                    matlab.ui.control.NumericEditField
        epsilonEditField_2Label         matlab.ui.control.Label
        epsilonEditField_2              matlab.ui.control.NumericEditField
        min_distEditField_2Label        matlab.ui.control.Label
        min_distEditField_2             matlab.ui.control.NumericEditField
        tile_sizeEditField_4Label       matlab.ui.control.Label
        tile_sizeEditField_4            matlab.ui.control.NumericEditField
        NEditField_2Label               matlab.ui.control.Label
        NEditField_2                    matlab.ui.control.NumericEditField
        corr_pointsTab                  matlab.ui.container.Tab
        window_lengthEditFieldLabel     matlab.ui.control.Label
        window_lengthEditField          matlab.ui.control.NumericEditField
        min_corrEditFieldLabel          matlab.ui.control.Label
        min_corrEditField               matlab.ui.control.NumericEditField
        plotcorrespondencesCheckBox     matlab.ui.control.CheckBox
        epiTab                          matlab.ui.container.Tab
        focallengthfEditFieldLabel      matlab.ui.control.Label
        focallengthfEditField           matlab.ui.control.NumericEditField
        kEditField_3Label               matlab.ui.control.Label
        kEditField_3                    matlab.ui.control.NumericEditField
        toleranceEditFieldLabel         matlab.ui.control.Label
        toleranceEditField              matlab.ui.control.NumericEditField
        disparityTab                    matlab.ui.container.Tab
        rEditFieldLabel                 matlab.ui.control.Label
        rEditField                      matlab.ui.control.NumericEditField
        window_rangeoddandpositiveLabel  matlab.ui.control.Label
        window_rangeoddandpositiveEditField  matlab.ui.control.NumericEditField
        methodEditFieldLabel            matlab.ui.control.Label
        methodEditField                 matlab.ui.control.NumericEditField
        thresholdDispDiffEditFieldLabel  matlab.ui.control.Label
        thresholdDispDiffEditField      matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        leftView_m % left view image
        rightView_m % right view image
        virtualView_m %virtual view image
    end


    methods (Access = private)

        % Value changing function: LeftViewEditField
        function LeftViewEditFieldValueChanging(app, event)
     
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg', '*.png'}, 'File Selector');
            imagepath = strcat(pathname, filename);
            set(app.LeftViewEditField, 'Value', imagepath)
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg', '*.png'}, 'File Selector');
            imagepath = strcat(pathname, filename);
            set(app.RightViewEditField, 'Value', imagepath)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Read out image path from Edit Field (GUI)
            imagepath = app.LeftViewEditField.Value;
            
            % Read in your GIF file. Don't forget to read in the colour map as it is
            % required for display.
            [I, map]=imread('loading.gif','Frames','all');
            
            % Create a figure to hold your splashscreen
            hfig=figure;
            set(hfig,'Menubar', 'none');
            set(hfig,'name','Please wait. Loading...','numbertitle','off');
            
            % Set a timer to dynamically update the plot every 0.1 sec
            t=timer('TimerFcn', {@loadingCallbackFcn, hfig, I, map},'ExecutionMode','FixedRate','Period',0.1);
            
            % Start the timer
            start(t);
                        
            % Remove title, axis labels, and tick labels
            %title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};

            app.leftView_m = imread(imagepath);
            
            %image(app.UIAxes, app.leftView_m);
            himage = imshow(app.leftView_m,'Parent',app.UIAxes,...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);

            % Set limits of axes
            app.UIAxes.XLim = [0 himage.XData(2)];
            app.UIAxes.YLim = [0 himage.YData(2)];
                                                
            % Clean-up
            stop(t);
            delete(t);
            delete(hfig);
           
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            % Read out image path from Edit Field (GUI)
            imagepath = app.RightViewEditField.Value;
            
            % Read in your GIF file. Don't forget to read in the colour map as it is
            % required for display.
            [I, map]=imread('loading.gif','Frames','all');
            
            % Create a figure to hold your splashscreen
            hfig=figure;
            set(hfig,'Menubar', 'none');
            set(hfig,'name','Please wait. Loading...','numbertitle','off');
            
            % Set a timer to dynamically update the plot every 0.1 sec
            t=timer('TimerFcn', {@loadingCallbackFcn, hfig, I, map},'ExecutionMode','FixedRate','Period',0.1);
            
            % Start the timer
            start(t);
            % Remove title, axis labels, and tick labels
            %title(app.UIAxes3, []);
            xlabel(app.UIAxes3, []);
            ylabel(app.UIAxes3, []);
            app.UIAxes3.XAxis.TickLabels = {};
            app.UIAxes3.YAxis.TickLabels = {};
                
            app.rightView_m = imread(imagepath);
            himage = imshow(app.rightView_m,'Parent',app.UIAxes3,...
                'XData', [1 app.UIAxes3.Position(3)], ...
                'YData', [1 app.UIAxes3.Position(4)]);

            % Set limits of axes
            app.UIAxes3.XLim = [0 himage.XData(2)];
            app.UIAxes3.YLim = [0 himage.YData(2)];
                                                
            % Clean-up
            stop(t);
            delete(t);
            delete(hfig);         
        end

        % Button pushed function: ShowvirtualViewButton
        function ShowvirtualViewButtonPushed(app, event)
                        
            %%%%%read out images and perspective
            imageL = app.leftView_m;
            imageR = app.rightView_m;
            p = app.perspectiveSlider.Value / 100;


            %%%%%read out PARAMETERS from PARAMETER SETTINGS %%%%%          
            %harris
            segment_length = app.segment_lenghtEditField_2.Value;
            k = app.kEditField_2.Value;
            epsilon = app.epsilonEditField_2.Value;
            min_dist = app.min_distEditField_2.Value;
            tile_size = [app.tile_sizeEditField_3.Value, app.tile_sizeEditField_4.Value];
            N = app.NEditField_2.Value;            
            harris_parameter = {segment_length, k, epsilon, min_dist, tile_size, N};
            
            %correspondences
            window_length = app.window_lengthEditField.Value;
            min_corr = app.min_corrEditField.Value;
            plot_corr = app.plotcorrespondencesCheckBox.Value;
            corres_parameter = {window_length, min_corr, plot_corr}; %0=false, 1=true
            
            %epi
            f = app.focallengthfEditField.Value; %3.957623257412620e+03; Brennweite aus Angabe
            %K = [f,0, (size(imageL,2)-1)/2;
            %    0, f, (size(imageL,1)-1)/2;
            %    0, 0, 1];
            %K = zeros(3,3); %for testing
            k = app.kEditField_3.Value;
            s = get_ransac_iterations(0.5, 0.5, k);
            %s = 100; %for testing
            tolerance = app.toleranceEditField.Value;
            epi_parameter = {f, s, k, tolerance};
            
            %disparity
            r = app.rEditField.Value;    %ungerade und positiv
            window_range = app.window_rangeoddandpositiveEditField.Value; %ungerade und positiv
            method = app.methodEditField.Value;
            thresholdDispDiff = app.thresholdDispDiffEditField.Value;            
            disp_parameter = {r, window_range, method,thresholdDispDiff};


            %%%%% GIF LOADING ANIMATION and FUNCTION CALL free_viewpoint() %%%%%
            % Read in GIF file for loading animation
%            [I, map]=imread('loading.gif','Frames','all');
            
            % Create a figure to hold splashscreen
%            hfig=figure;
%            set(hfig,'Menubar', 'none');
%            set(hfig,'name','Please wait. Loading...','numbertitle','off');
            
            % Set a timer to dynamically update the plot every 0.1 sec
%            t=timer('TimerFcn', {@loadingCallbackFcn, hfig, I, map},'ExecutionMode','FixedRate','Period',0.1);
            
            % Start the timer
%            start(t);
            
            % CALL THE FUNCTION free_viewpoint %
            tic;
            output_image = free_viewpoint(imageL, imageR, p, harris_parameter, ...
                corres_parameter, epi_parameter, disp_parameter, f);
            app.virtualView_m = output_image; %save output_image as property
            elapsed_time = toc
            
            
            %%%%% DISPLAY IMAGE - ADJUST SIZE %%%%%
            % Remove axis labels, and tick labels
            xlabel(app.UIAxes2, []);
            ylabel(app.UIAxes2, []);
            app.UIAxes2.XAxis.TickLabels = {};
            app.UIAxes2.YAxis.TickLabels = {};
             
            % Display image and stretch to fill axes
            himage = imshow(output_image, 'Parent', app.UIAxes2,...
               'XData', [1 app.UIAxes2.Position(3)], ...
               'YData', [1 app.UIAxes2.Position(4)]);
            
            % Set limits of axes
            app.UIAxes2.XLim = [0 himage.XData(2)];
            app.UIAxes2.YLim = [0 himage.YData(2)];
                                   
            % Clean-up
%            stop(t);
%            delete(t);
%            delete(hfig);
            
        end

        % Button pushed function: extractfigureButton
        function extractfigureButtonPushed(app, event)
            figure
            imshow(app.leftView_m);
            title('Left View');
        end

        % Button pushed function: extractfigureButton_3
        function extractfigureButton_3Pushed(app, event)
            figure
            imshow(app.rightView_m);
            title('Right View');
        end

        % Button pushed function: extractfigureButton_2
        function extractfigureButton_2Pushed(app, event)
            p = app.perspectiveSlider.Value / 100;
            figure
            imshow(app.virtualView_m);
            title(['Virtual View (p=', num2str(p),')']);
        end

        % Button pushed function: extractPlotsButton
        function extractPlotsButtonPushed(app, event)
            figure
            subplot(1,3,1)
            imshow(app.leftView_m);
            title('leftView')
            subplot(1,3,2)
            imshow(app.virtualView_m);
            p=app.perspectiveSlider.Value/100;
            title(['virtualView (p=', num2str(p),')'])
            subplot(1,3,3)
            imshow(app.rightView_m);
            title('rightView')
        end

        % Value changed function: perspectiveSlider
        function perspectiveSliderValueChanged(app, event)
            %value = app.perspectiveSlider.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadImagesPanel = uipanel(app.UIFigure);
            app.LoadImagesPanel.Title = 'Load Images';
            app.LoadButton = uibutton(app.LoadImagesPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LeftViewEditFieldLabel = uilabel(app.LoadImagesPanel);
            app.LeftViewEditFieldLabel.Text = 'Left View';
            app.LeftViewEditField = uieditfield(app.LoadImagesPanel, 'text');
            app.LeftViewEditField.ValueChangingFcn = createCallbackFcn(app, @LeftViewEditFieldValueChanging, true);
            app.LoadButton_2 = uibutton(app.LoadImagesPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.RightViewEditFieldLabel = uilabel(app.LoadImagesPanel);
            app.RightViewEditFieldLabel.Text = 'Right View';
            app.RightViewEditField = uieditfield(app.LoadImagesPanel, 'text');
            app.BrowseButton = uibutton(app.LoadImagesPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.LoadImagesPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.PlotsPanel = uipanel(app.UIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.UIAxes = uiaxes(app.PlotsPanel);
            title(app.UIAxes, 'Image Left View')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.PlotsPanel);
            title(app.UIAxes2, 'virtual View')
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTickLabel = '';
            app.UIAxes3 = uiaxes(app.PlotsPanel);
            title(app.UIAxes3, 'Image Right View')
            app.UIAxes3.XTickLabel = '';
            app.UIAxes3.YTickLabel = '';
            app.extractfigureButton = uibutton(app.PlotsPanel, 'push');
            app.extractfigureButton.ButtonPushedFcn = createCallbackFcn(app, @extractfigureButtonPushed, true);
            app.extractfigureButton.Text = 'extract figure';
            app.extractfigureButton_2 = uibutton(app.PlotsPanel, 'push');
            app.extractfigureButton_2.ButtonPushedFcn = createCallbackFcn(app, @extractfigureButton_2Pushed, true);
            app.extractfigureButton_2.Text = 'extract figure';
            app.extractfigureButton_3 = uibutton(app.PlotsPanel, 'push');
            app.extractfigureButton_3.ButtonPushedFcn = createCallbackFcn(app, @extractfigureButton_3Pushed, true);
            app.extractfigureButton_3.Text = 'extract figure';
            app.VirtualViewOptionsPanel = uipanel(app.UIFigure);
            app.VirtualViewOptionsPanel.Title = 'Virtual View Options';
            app.perspectiveSliderLabel = uilabel(app.VirtualViewOptionsPanel);
            app.perspectiveSliderLabel.Text = 'perspective [%]';
            app.perspectiveSlider = uislider(app.VirtualViewOptionsPanel);
            app.perspectiveSlider.ValueChangedFcn = createCallbackFcn(app, @perspectiveSliderValueChanged, true);
            app.perspectiveSlider.Value = 50;
            app.ShowvirtualViewButton = uibutton(app.VirtualViewOptionsPanel, 'push');
            app.ShowvirtualViewButton.ButtonPushedFcn = createCallbackFcn(app, @ShowvirtualViewButtonPushed, true);
            app.ShowvirtualViewButton.Text = 'Show virtual View';
            app.extractPlotsButton = uibutton(app.VirtualViewOptionsPanel, 'push');
            app.extractPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @extractPlotsButtonPushed, true);
            app.extractPlotsButton.Text = 'extract Plots';
            app.ParameterSettingsPanel = uipanel(app.UIFigure);
            app.ParameterSettingsPanel.Title = 'Parameter Settings';
            app.TabGroup = uitabgroup(app.ParameterSettingsPanel);
            app.harrisTab = uitab(app.TabGroup);
            app.harrisTab.Title = 'harris';
            app.tile_sizeEditField_3 = uieditfield(app.harrisTab, 'numeric');
            app.tile_sizeEditField_3.Value = 25;
            app.segment_lenghtEditField_2Label = uilabel(app.harrisTab);
            app.segment_lenghtEditField_2Label.Text = 'segment_lenght';
            app.segment_lenghtEditField_2 = uieditfield(app.harrisTab, 'numeric');
            app.segment_lenghtEditField_2.Value = 9;
            app.kEditField_2Label = uilabel(app.harrisTab);
            app.kEditField_2Label.Text = 'k';
            app.kEditField_2 = uieditfield(app.harrisTab, 'numeric');
            app.kEditField_2.Value = 0.05;
            app.epsilonEditField_2Label = uilabel(app.harrisTab);
            app.epsilonEditField_2Label.Text = 'epsilon';
            app.epsilonEditField_2 = uieditfield(app.harrisTab, 'numeric');
            app.epsilonEditField_2.Value = 10000;
            app.min_distEditField_2Label = uilabel(app.harrisTab);
            app.min_distEditField_2Label.Text = 'min_dist';
            app.min_distEditField_2 = uieditfield(app.harrisTab, 'numeric');
            app.min_distEditField_2.Value = 20;
            app.tile_sizeEditField_4Label = uilabel(app.harrisTab);
            app.tile_sizeEditField_4Label.Text = 'tile_size';
            app.tile_sizeEditField_4 = uieditfield(app.harrisTab, 'numeric');
            app.tile_sizeEditField_4.Value = 25;
            app.NEditField_2Label = uilabel(app.harrisTab);
            app.NEditField_2Label.Text = 'N';
            app.NEditField_2 = uieditfield(app.harrisTab, 'numeric');
            app.NEditField_2.Value = 10;
            app.corr_pointsTab = uitab(app.TabGroup);
            app.corr_pointsTab.Title = 'corr_points';
            app.window_lengthEditFieldLabel = uilabel(app.corr_pointsTab);
            app.window_lengthEditFieldLabel.Text = 'window_length';
            app.window_lengthEditField = uieditfield(app.corr_pointsTab, 'numeric');
            app.window_lengthEditField.Value = 25;
            app.min_corrEditFieldLabel = uilabel(app.corr_pointsTab);
            app.min_corrEditFieldLabel.Text = 'min_corr';
            app.min_corrEditField = uieditfield(app.corr_pointsTab, 'numeric');
            app.min_corrEditField.Value = 0.95;
            app.plotcorrespondencesCheckBox = uicheckbox(app.corr_pointsTab);
            app.plotcorrespondencesCheckBox.Text = 'plot correspondences';
            app.epiTab = uitab(app.TabGroup);
            app.epiTab.Title = 'epi';
            app.focallengthfEditFieldLabel = uilabel(app.epiTab);
            app.focallengthfEditFieldLabel.Text = 'focal length f';
            app.focallengthfEditField = uieditfield(app.epiTab, 'numeric');
            app.focallengthfEditField.Value = 3957.62325741262;
            app.kEditField_3Label = uilabel(app.epiTab);
            app.kEditField_3Label.Text = 'k';
            app.kEditField_3 = uieditfield(app.epiTab, 'numeric');
            app.kEditField_3.Value = 12;
            app.toleranceEditFieldLabel = uilabel(app.epiTab);
            app.toleranceEditFieldLabel.Text = 'tolerance';
            app.toleranceEditField = uieditfield(app.epiTab, 'numeric');
            app.toleranceEditField.Value = 0.01;
            app.disparityTab = uitab(app.TabGroup);
            app.disparityTab.Title = 'disparity';
            app.rEditFieldLabel = uilabel(app.disparityTab);
            app.rEditFieldLabel.Text = 'r ';
            app.rEditField = uieditfield(app.disparityTab, 'numeric');
            app.rEditField.Value = 8;
            app.window_rangeoddandpositiveLabel = uilabel(app.disparityTab);
            app.window_rangeoddandpositiveLabel.Text = {'window_range'; ' (odd and positive)'};
            app.window_rangeoddandpositiveEditField = uieditfield(app.disparityTab, 'numeric');
            app.window_rangeoddandpositiveEditField.Value = 151;
            app.methodEditFieldLabel = uilabel(app.disparityTab);
            app.methodEditFieldLabel.Text = 'method';
            app.methodEditField = uieditfield(app.disparityTab, 'numeric');
            app.methodEditField.Value = 4;
            app.thresholdDispDiffEditFieldLabel = uilabel(app.disparityTab);
            app.thresholdDispDiffEditFieldLabel.Text = 'thresholdDispDiff';
            app.thresholdDispDiffEditField = uieditfield(app.disparityTab, 'numeric');
            app.thresholdDispDiffEditField.Value = 6;
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end